classdef prototype1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        JoystickStatusPanel       matlab.ui.container.Panel
        SWRLamp                   matlab.ui.control.Lamp
        SWRLampLabel              matlab.ui.control.Label
        SWLLamp                   matlab.ui.control.Lamp
        SWLLampLabel              matlab.ui.control.Label
        StartLamp_2               matlab.ui.control.Lamp
        StartLamp_2Label          matlab.ui.control.Label
        SelectLamp                matlab.ui.control.Lamp
        SelectLampLabel           matlab.ui.control.Label
        RB2Lamp                   matlab.ui.control.Lamp
        RB2LampLabel              matlab.ui.control.Label
        LB2Lamp                   matlab.ui.control.Lamp
        LB2LampLabel              matlab.ui.control.Label
        RU1Lamp                   matlab.ui.control.Lamp
        RU1LampLabel              matlab.ui.control.Label
        LU1Lamp                   matlab.ui.control.Lamp
        LU1LampLabel              matlab.ui.control.Label
        B4Lamp                    matlab.ui.control.Lamp
        B4LampLabel               matlab.ui.control.Label
        B3Lamp                    matlab.ui.control.Lamp
        B3LampLabel               matlab.ui.control.Label
        B2Lamp                    matlab.ui.control.Lamp
        B2LampLabel               matlab.ui.control.Label
        B1Lamp                    matlab.ui.control.Lamp
        B1LampLabel               matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        LinkButton                matlab.ui.control.StateButton
        CameraLabel               matlab.ui.control.Label
        PairButton                matlab.ui.control.StateButton
        JoystickPortEditField     matlab.ui.control.NumericEditField
        JoystickPortLabel         matlab.ui.control.Label
        BatteryVoltageGauge       matlab.ui.control.Gauge
        BatteryVoltageGaugeLabel  matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        Aux4Switch                matlab.ui.control.Switch
        Aux4SwitchLabel           matlab.ui.control.Label
        MapPanel                  matlab.ui.container.Panel
        ConnectButton             matlab.ui.control.StateButton
        BudrateDropDown           matlab.ui.control.DropDown
        BudrateDropDownLabel      matlab.ui.control.Label
        Aux3Switch                matlab.ui.control.Switch
        Aux3SwitchLabel           matlab.ui.control.Label
        Aux2Switch                matlab.ui.control.Switch
        Aux2SwitchLabel           matlab.ui.control.Label
        PortDropDown              matlab.ui.control.DropDown
        PortDropDownLabel         matlab.ui.control.Label
        Altimeter                 Aero.ui.control.Altimeter
        AltimeterLabel            matlab.ui.control.Label
        HeadingIndicator          Aero.ui.control.HeadingIndicator
        HeadingIndicatorLabel     matlab.ui.control.Label
        ArtificialHorizon         Aero.ui.control.ArtificialHorizon
        ArtificialHorizonLabel    matlab.ui.control.Label
        AirspeedIndicator         Aero.ui.control.AirspeedIndicator
        AirspeedIndicatorLabel    matlab.ui.control.Label
        Aux1Switch                matlab.ui.control.ToggleSwitch
        Aux1SwitchLabel           matlab.ui.control.Label
        Mode                      matlab.ui.control.ToggleSwitch
        ModeLabel                 matlab.ui.control.Label
        StateLamp                 matlab.ui.control.Lamp
        StateLampLabel            matlab.ui.control.Label
        ArmDisarmButton           matlab.ui.control.StateButton
    end

    
      properties (Access = private)
          
          serialPort 
          joystick
          fid                     
          
          
      end


        methods (Access = private)
            
        
                        function Main(app, ~)
                        % Setup the geoaxes once outside the loop
                        gx = geoaxes(app.MapPanel);
                        geolimits(gx, [-90 90], [-180 180]); % Set map limits
                        geobasemap(gx, 'satellite'); % Set map style
                 
                        if(app.PairButton.Value == 1)
                        runPythonScript(app);
                        end
            
                         while true
                                try
                                    % Read data from the serial port
                                    data = readline(app.serialPort);
                                    values = str2double(strsplit(data, ','));
                        
                                    % Ensure we have enough values
                                    if length(values) >= 8
                                        % Update the instruments and UI components
                                        app.AirspeedIndicator.Value = values(1);
                                        app.Altimeter.Value = values(2);
                                        app.BatteryVoltageGauge.Value = values(3);
                                        app.ArtificialHorizon.Pitch = values(4);
                                        app.ArtificialHorizon.Roll = values(5);
                                        app.HeadingIndicator.Heading = values(6);
                        
                                        % Update the geoplot only if the coordinates have changed
                                        lat = values(7);
                                        lon = values(8);

                                        geoplot(gx, lat, lon, 'rv'); % Example marker plot


                                   end
                                      
                                      % Read joystick values
                                    x = axis(app.joystick,1);
                                    y = axis(app.joystick,2);
                                    z = axis(app.joystick,3);
                                    w = axis(app.joystick,4);
                    
                                    Pov = pov(app.joystick,1);
                    
                                    buttonValues = button(app.joystick, 1:12);
                    
                                    % Format data as a string
                                    data = sprintf('X: %f, Y: %f, Z: %f, W: %f, Pov: %d, B1: %d, B2: %d, B3: %d, B4: %d, B5: %d, B6: %d, B7: %d, B8: %d, B9: %d, B10: %d, B11: %d, B12: %d', ...
                                                   x, y, z, w, Pov, buttonValues);

                                    % Write data to the serial port
                                    writeline(app.serialPort, data);
                                    
                                    % Update button lamps
                                    lamps = {app.B1Lamp, app.B2Lamp, app.B3Lamp, app.B4Lamp, app.LU1Lamp, app.RU1Lamp, app.LB2Lamp, app.RB2Lamp, app.SelectLamp, app.StartLamp_2, app.SWLLamp, app.SWRLamp};
                                    colors = repmat({'green'}, 1, 12);  % Default to green
                    
                                    for i = 1:12
                                        if buttonValues(i) == 1
                                            colors{i} = 'red';
                                        end
                                    end
                                    
                                    % Apply colors to lamps
                                    for i = 1:12
                                        lamps{i}.Color = colors{i};
                                    end   


                                    % Pause to allow other operations
                                    pause(0.1);
                        
                                catch exception

                                    if(isempty(app.serialPort) == 0)
                                   
                                    disp('Error occurred while reading from the serial port or updating the UI:');
                                    disp(exception.message);
                                   
                                    end

                                end
                          end
        
           
            end

           function runPythonScript(~)
            % Ensure that Python is configured correctly in MATLAB
            if count(py.sys.path,'') == 0
            insert(py.sys.path,int32(0),'');
            end
    
             % Run the Python script asynchronously using system command
             try

             status = system('python Object_tracking_Servocontrol.py &');
        
             if status == 0
             % Python script executed successfully
             disp('Python script executed successfully.');
             end
        
            catch ME
            % Handle error if the system command did not run successfully
            disp('Failed to start the Python script.');
            disp(ME.message);
            end
           end


           function OpenPort(app)
            
            comPort = app.PortDropDown.Value;
            id = app.JoystickPortEditField.Value;
            baudRate =  str2double(app.BudrateDropDown.Value);
            app.serialPort = serialport(comPort,baudRate);

            if(app.PairButton.Value == 1)
            app.joystick = vrjoystick(id);
            end

            pause(0.001);

            Main(app); 


          end
        end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ArmDisarmButton
        function ArmDisarmButtonValueChanged(app, event)
            value0 = app.ArmDisarmButton.Value;
            
            if value0 == 1
                
            app.StateLamp.Color = 'green';
            gx = geoaxes(app.MapPanel);   
            geoplot(gx, 24.16, 72.42, 'rv'); % Example marker plot
            geolimits(gx, [-90 90], [-180 180]); % Set map limits
            geobasemap(gx, 'satellite'); % Set map style
                
                
            end
            
            if value0 == 0
            app.StateLamp.Color = 'red';
            end
            
            
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value5 = app.ConnectButton.Value;
            
            if(value5 == 1)
                
                app.ConnectButton.Text = 'Disconnect';
                OpenPort(app);
            end   


            if(value5 == 0)
                
                app.ConnectButton.Text = 'Connect';
                % Close and delete the serial port object
                if isvalid(app.serialPort)
                clear app.serialPort
                clear app.joystick
                end
                
         
           end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ArmDisarmButton = uibutton(app.UIFigure, 'state');
            app.ArmDisarmButton.ValueChangedFcn = createCallbackFcn(app, @ArmDisarmButtonValueChanged, true);
            app.ArmDisarmButton.Text = {'Arm/Disarm'; ''; ''};
            app.StateLampLabel = uilabel(app.UIFigure);
            app.StateLampLabel.Text = 'State';
            app.StateLamp = uilamp(app.UIFigure);
            app.StateLamp.Tag = 'indecator';
            app.ModeLabel = uilabel(app.UIFigure);
            app.ModeLabel.Text = 'Mode';
            app.Mode = uiswitch(app.UIFigure, 'toggle');
            app.Mode.Items = {'Auto', 'Manual'};
            app.Mode.Value = 'Auto';
            app.Aux1SwitchLabel = uilabel(app.UIFigure);
            app.Aux1SwitchLabel.Text = 'Aux 1';
            app.Aux1Switch = uiswitch(app.UIFigure, 'toggle');
            app.Aux1Switch.Items = {'High', 'Low'};
            app.Aux1Switch.Value = 'High';
            app.AirspeedIndicatorLabel = uilabel(app.UIFigure);
            app.AirspeedIndicatorLabel.Text = 'Airspeed Indicator';
            app.AirspeedIndicator = uiaeroairspeed(app.UIFigure);
            app.ArtificialHorizonLabel = uilabel(app.UIFigure);
            app.ArtificialHorizonLabel.Text = 'ArtificialHorizon';
            app.ArtificialHorizon = uiaerohorizon(app.UIFigure);
            app.HeadingIndicatorLabel = uilabel(app.UIFigure);
            app.HeadingIndicatorLabel.Text = 'HeadingIndicator';
            app.HeadingIndicator = uiaeroheading(app.UIFigure);
            app.AltimeterLabel = uilabel(app.UIFigure);
            app.AltimeterLabel.Text = 'Altimeter';
            app.Altimeter = uiaeroaltimeter(app.UIFigure);
            app.PortDropDownLabel = uilabel(app.UIFigure);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.UIFigure);
            app.PortDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11', 'COM12', 'COM13', ''};
            app.PortDropDown.Value = 'COM1';
            app.Aux2SwitchLabel = uilabel(app.UIFigure);
            app.Aux2SwitchLabel.Text = 'Aux 2';
            app.Aux2Switch = uiswitch(app.UIFigure, 'slider');
            app.Aux3SwitchLabel = uilabel(app.UIFigure);
            app.Aux3SwitchLabel.Text = 'Aux 3';
            app.Aux3Switch = uiswitch(app.UIFigure, 'slider');
            app.BudrateDropDownLabel = uilabel(app.UIFigure);
            app.BudrateDropDownLabel.Text = 'Budrate';
            app.BudrateDropDown = uidropdown(app.UIFigure);
            app.BudrateDropDown.Items = {'9600', '57600', '115200'};
            app.BudrateDropDown.ItemsData = {'9600', '57600', '115200', ''};
            app.BudrateDropDown.Value = '9600';
            app.ConnectButton = uibutton(app.UIFigure, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.MapPanel = uipanel(app.UIFigure);
            app.MapPanel.Title = 'Map';
            app.Aux4SwitchLabel = uilabel(app.UIFigure);
            app.Aux4SwitchLabel.Text = {'Aux4'; ''};
            app.Aux4Switch = uiswitch(app.UIFigure, 'slider');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'icon.jpg';
            app.BatteryVoltageGaugeLabel = uilabel(app.UIFigure);
            app.BatteryVoltageGaugeLabel.Text = {'Battery Voltage'; ''};
            app.BatteryVoltageGauge = uigauge(app.UIFigure, 'circular');
            app.BatteryVoltageGauge.Limits = [0 16];
            app.BatteryVoltageGauge.MajorTicks = [0 2 4 6 8 10 12.5 14.5];
            app.JoystickPortLabel = uilabel(app.UIFigure);
            app.JoystickPortLabel.Text = 'Joystick Port:';
            app.JoystickPortEditField = uieditfield(app.UIFigure, 'numeric');
            app.JoystickPortEditField.Value = 1;
            app.PairButton = uibutton(app.UIFigure, 'state');
            app.PairButton.Text = 'Pair';
            app.CameraLabel = uilabel(app.UIFigure);
            app.CameraLabel.Text = 'Camera:';
            app.LinkButton = uibutton(app.UIFigure, 'state');
            app.LinkButton.Text = 'Link';
            app.JoystickStatusPanel = uipanel(app.UIFigure);
            app.JoystickStatusPanel.Title = 'Joystick Status Panel';
            app.UIAxes = uiaxes(app.JoystickStatusPanel);
            title(app.UIAxes, 'Analog Axis')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Values')
            zlabel(app.UIAxes, 'Z')
            app.B1LampLabel = uilabel(app.JoystickStatusPanel);
            app.B1LampLabel.Text = {'B1'; ''};
            app.B1Lamp = uilamp(app.JoystickStatusPanel);
            app.B2LampLabel = uilabel(app.JoystickStatusPanel);
            app.B2LampLabel.Text = {'B2'; ''};
            app.B2Lamp = uilamp(app.JoystickStatusPanel);
            app.B3LampLabel = uilabel(app.JoystickStatusPanel);
            app.B3LampLabel.Text = {'B3'; ''};
            app.B3Lamp = uilamp(app.JoystickStatusPanel);
            app.B4LampLabel = uilabel(app.JoystickStatusPanel);
            app.B4LampLabel.Text = {'B4'; ''};
            app.B4Lamp = uilamp(app.JoystickStatusPanel);
            app.LU1LampLabel = uilabel(app.JoystickStatusPanel);
            app.LU1LampLabel.Text = {'LU1'; ''; ''};
            app.LU1Lamp = uilamp(app.JoystickStatusPanel);
            app.RU1LampLabel = uilabel(app.JoystickStatusPanel);
            app.RU1LampLabel.Text = {'RU1'; ''; ''};
            app.RU1Lamp = uilamp(app.JoystickStatusPanel);
            app.LB2LampLabel = uilabel(app.JoystickStatusPanel);
            app.LB2LampLabel.Text = {'LB2'; ''; ''};
            app.LB2Lamp = uilamp(app.JoystickStatusPanel);
            app.RB2LampLabel = uilabel(app.JoystickStatusPanel);
            app.RB2LampLabel.Text = {'RB2'; ''; ''; ''};
            app.RB2Lamp = uilamp(app.JoystickStatusPanel);
            app.SelectLampLabel = uilabel(app.JoystickStatusPanel);
            app.SelectLampLabel.Text = {'Select'; ''; ''; ''};
            app.SelectLamp = uilamp(app.JoystickStatusPanel);
            app.StartLamp_2Label = uilabel(app.JoystickStatusPanel);
            app.StartLamp_2Label.Text = {'Start'; ''; ''};
            app.StartLamp_2 = uilamp(app.JoystickStatusPanel);
            app.SWLLampLabel = uilabel(app.JoystickStatusPanel);
            app.SWLLampLabel.Text = {'SWL'; ''};
            app.SWLLamp = uilamp(app.JoystickStatusPanel);
            app.SWRLampLabel = uilabel(app.JoystickStatusPanel);
            app.SWRLampLabel.Text = {'SWR'; ''};
            app.SWRLamp = uilamp(app.JoystickStatusPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prototype1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end